classdef SpecSenseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GenerateTrainingDataTab         matlab.ui.container.Tab
        StartIdxEditField               matlab.ui.control.NumericEditField
        StartIdxEditFieldLabel          matlab.ui.control.Label
        RawSignalStoreIntervalEditField  matlab.ui.control.NumericEditField
        RawSignalStoreIntervalEditFieldLabel  matlab.ui.control.Label
        TxGaindBEditField_2             matlab.ui.control.NumericEditField
        TxGaindBEditField_2Label        matlab.ui.control.Label
        ConfigureHWButton_2             matlab.ui.control.Button
        RxGaindBEditField_2             matlab.ui.control.NumericEditField
        RxGaindBEditFieldLabel_2        matlab.ui.control.Label
        USRPPresentDropDown_2           matlab.ui.control.DropDown
        USRPPresentDropDown_2Label      matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        HardwareConfigurationLabel      matlab.ui.control.Label
        USRPDropDown                    matlab.ui.control.DropDown
        USRPDropDownLabel               matlab.ui.control.Label
        CollectSignalsButton            matlab.ui.control.StateButton
        CenterFreqGHzEditField_2        matlab.ui.control.NumericEditField
        CenterFreqGHzEditField_2Label   matlab.ui.control.Label
        PRFxPulseDurationEditField      matlab.ui.control.EditField
        PRFxPulseDurationLabel          matlab.ui.control.Label
        SweepDirectionEditField         matlab.ui.control.EditField
        SweepDirectionEditFieldLabel    matlab.ui.control.Label
        BandwidthrangeinMHzEditField    matlab.ui.control.EditField
        BandwidthrangeinMHzEditFieldLabel  matlab.ui.control.Label
        DopplerHzEditField              matlab.ui.control.EditField
        DopplerHzEditFieldLabel         matlab.ui.control.Label
        SSBPeriodmsEditField            matlab.ui.control.EditField
        SSBPeriodmsEditFieldLabel       matlab.ui.control.Label
        BandwidthMHzEditField_2         matlab.ui.control.EditField
        BandwidthMHzEditField_2Label    matlab.ui.control.Label
        SubcarrierspacingKHzEditField   matlab.ui.control.EditField
        SubcarrierspacingKHzEditFieldLabel  matlab.ui.control.Label
        NoofFramesEditField             matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel        matlab.ui.control.Label
        SampleRateMSPSEditField         matlab.ui.control.NumericEditField
        SampleRateMSPSEditFieldLabel    matlab.ui.control.Label
        FrameDurationmsEditField        matlab.ui.control.NumericEditField
        FrameDurationmsEditFieldLabel   matlab.ui.control.Label
        DownlinkUplinkDropDown          matlab.ui.control.DropDown
        DownlinkUplinkDropDownLabel     matlab.ui.control.Label
        ModulationDropDown              matlab.ui.control.DropDown
        ModulationDropDownLabel         matlab.ui.control.Label
        SweepIntervalDropDown           matlab.ui.control.DropDown
        SweepIntervalDropDownLabel      matlab.ui.control.Label
        RADARConfigurationLabel         matlab.ui.control.Label
        NRConfigurationLabel            matlab.ui.control.Label
        GeneralConfigurationLabel       matlab.ui.control.Label
        SpectrogramSizePixelsEditField  matlab.ui.control.EditField
        SpectrogramSizePixelsEditFieldLabel  matlab.ui.control.Label
        DataFolderEditField             matlab.ui.control.EditField
        DataFolderEditFieldLabel        matlab.ui.control.Label
        TrainandEvaluateTab             matlab.ui.container.Tab
        Button_2                        matlab.ui.control.Button
        LoadNetworkButton_3             matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        ImageSizePixelsEditField        matlab.ui.control.EditField
        ImageSizePixelsEditFieldLabel   matlab.ui.control.Label
        NetworkNameEditField            matlab.ui.control.EditField
        NetworkNameEditFieldLabel       matlab.ui.control.Label
        EvaluationFolderEditField       matlab.ui.control.EditField
        EvaluationFolderEditFieldLabel  matlab.ui.control.Label
        EvaluationConfigurationLabel    matlab.ui.control.Label
        ExecutionEnvironmentDropDown    matlab.ui.control.DropDown
        ExecutionEnvironmentDropDownLabel  matlab.ui.control.Label
        InitialLearningRateEditField    matlab.ui.control.EditField
        InitialLearningRateLabel        matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.EditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        PlotsDropDown                   matlab.ui.control.DropDown
        PlotsDropDownLabel              matlab.ui.control.Label
        SolverNameDropDown              matlab.ui.control.DropDown
        SolverNameLabel                 matlab.ui.control.Label
        NeuralNetConfigurationLabel     matlab.ui.control.Label
        ClassNamesEditField             matlab.ui.control.EditField
        ClassNamesEditFieldLabel        matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        BaseNetworkDropDown             matlab.ui.control.DropDown
        BaseNetworkDropDownLabel        matlab.ui.control.Label
        DataFolderEditField_2           matlab.ui.control.EditField
        DataFolderEditField_2Label      matlab.ui.control.Label
        GeneralConfigurationLabel_2     matlab.ui.control.Label
        SoftwareTestTab                 matlab.ui.container.Tab
        Button_3                        matlab.ui.control.Button
        DataFolderEditField_3           matlab.ui.control.EditField
        DataFolderEditField_3Label      matlab.ui.control.Label
        LoopButton_2                    matlab.ui.control.StateButton
        LoadNetworkButton               matlab.ui.control.Button
        NetworkEditField_2              matlab.ui.control.EditField
        NetworkEditField_2Label         matlab.ui.control.Label
        ClassifyButton_2                matlab.ui.control.Button
        InputSpectrogramTextArea        matlab.ui.control.TextArea
        InputSpectrogramTextAreaLabel   matlab.ui.control.Label
        BrowseButton_3                  matlab.ui.control.Button
        HardwareTestTab                 matlab.ui.container.Tab
        TxGaindBEditField               matlab.ui.control.NumericEditField
        TxGaindBEditFieldLabel          matlab.ui.control.Label
        LoopButton                      matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.Button
        DataFolderEditField_4           matlab.ui.control.EditField
        DataFolderEditField_4Label      matlab.ui.control.Label
        NetworkEditField                matlab.ui.control.EditField
        NetworkEditFieldLabel           matlab.ui.control.Label
        LoadNetworkButton_2             matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        RxGaindBEditField               matlab.ui.control.NumericEditField
        RxGaindBEditFieldLabel          matlab.ui.control.Label
        CenterFreqGHzEditField          matlab.ui.control.NumericEditField
        CenterFreqGHzEditFieldLabel     matlab.ui.control.Label
        SampleRateMSPSEditField_2       matlab.ui.control.NumericEditField
        SampleRateMSPSEditField_2Label  matlab.ui.control.Label
        ConfigureHWButton               matlab.ui.control.Button
        USRPPresentDropDown             matlab.ui.control.DropDown
        USRPPresentDropDownLabel        matlab.ui.control.Label
        SignalTypeDropDown              matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        ClassifyandLabelButton          matlab.ui.control.Button
        GenerateButton_4                matlab.ui.control.Button
        Sig2TextArea                    matlab.ui.control.TextArea
        Sig2TextAreaLabel               matlab.ui.control.Label
        Sig1TextArea                    matlab.ui.control.TextArea
        Sig1TextAreaLabel               matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        rxSpectrogramFilePath;
        nrFilePath;
        radarFilePath;

        inputSpectrogram;
        pauseAutoplay;
        selectedTab;
        autoTxStoppped;
        currentSection = "RADAR and NR";
        RadioConnected = false;
        sig1Info;
        sig2Info;
        signalGenerated = false;
        generationAbort = false;
        freqSpan;
        timeSpan;
        firstSignal;
        secondSignal;
        defaultRate = 61.44;
        defaultDuration = 20;
        trainingDataFolder;
        testDataFolder;
        radioConfigured = false;

    end

    methods (Access = private)

        function results = setAxesSpan(app,sr, duration)
            imageLength = str2double(split(app.SpectrogramSizePixelsEditField.Value));
            f = sr/(imageLength(1)-1);
            t = app.FrameDurationmsEditField.Value/(imageLength(1)-1);


            app.freqSpan= [-sr/2:f:sr/2];
            app.timeSpan = [0:t:duration];
            app.UIAxes.XLim = [-sr/2 sr/2];
            app.UIAxes.YLim = [0 duration];
            app.UIAxes.XTick =  linspace(-sr/2,sr/2,9);
            app.UIAxes.XTickLabel = string( linspace(-sr/2,sr/2,9));
            app.UIAxes.YTick = linspace(0,duration,9);
            app.UIAxes.YTickLabel = string(linspace(0,duration,9));

            app.UIAxes_2.XLim = [-sr/2 sr/2];
            app.UIAxes_2.YLim = [0 duration];
            app.UIAxes_2.XTick =  linspace(-sr/2,sr/2,9);
            app.UIAxes_2.XTickLabel = string( linspace(-sr/2,sr/2,9));
            app.UIAxes_2.YTick = linspace(0,duration,9);
            app.UIAxes_2.YTickLabel = string(linspace(0,duration,9));

            app.UIAxes2.XLim = [-sr/2 sr/2];
            app.UIAxes2.YLim = [0 duration];
            app.UIAxes2.XTick =  linspace(-sr/2,sr/2,9);
            app.UIAxes2.XTickLabel = string( linspace(-sr/2,sr/2,9));
            app.UIAxes2.YTick = linspace(0,duration,9);
            app.UIAxes2.YTickLabel = string(linspace(0,duration,9));

            app.UIAxes3.XLim = [-sr/2 sr/2];
            app.UIAxes3.YLim = [0 duration];
            app.UIAxes3.XTick =  linspace(-sr/2,sr/2,9);
            app.UIAxes3.XTickLabel = string( linspace(-sr/2,sr/2,9));
            app.UIAxes3.YTick = linspace(0,duration,9);
            app.UIAxes3.YTickLabel = string(linspace(0,duration,9));

            if app.selectedTab == app.TrainandEvaluateTab
                title(app.UIAxes2, 'Input Spectrogram');
                title(app.UIAxes3, 'Spectrogram after Signal Classification');
                title(app.UIAxes, '');
                title(app.UIAxes_2, '');
                app.UIAxes.XLim = [0 1];
                app.UIAxes.YLim = [0 1];
                app.UIAxes.XTick = [];
                app.UIAxes.XTickLabel = {};
                app.UIAxes.YTick = [];
                app.UIAxes.YTickLabel = {};
                app.UIAxes_2.XLim = [0 1];
                app.UIAxes_2.YLim = [0 1];
                app.UIAxes_2.XTick = [];
                app.UIAxes_2.XTickLabel = {};
                app.UIAxes_2.YTick = [];
                app.UIAxes_2.YTickLabel = {};
                xlabel(app.UIAxes_2, '')
                ylabel(app.UIAxes_2, '')
                xlabel(app.UIAxes, '')
                ylabel(app.UIAxes, '')

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Signal Classification with Deep Learning';
            app.testDataFolder = app.DataFolderEditField_3.Value;
            app.trainingDataFolder = app.DataFolderEditField.Value;
            app.ConfigureHWButton.Enable = "off";
            ret = loadTrainedNetwork(app.NetworkEditField.Value);
            if ret == 1
                app.StatusLabel.Text = "Status:AI loading Success";
            else
                app.StatusLabel.Text = "Status:AI Loading Failed";
            end
            %sr = app.SampleRateMSPSEditField.Value;     % MHz
            %duration = app.FrameDurationmsEditField.Value;
            %app.setAxesSpan(sr, duration);
            value = app.DataFolderEditField_4.Value;
            if exist(fullfile(pwd, value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            paramsFilePath = fullfile(pwd, value, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                app.SampleRateMSPSEditField_2.Value = app.defaultRate;
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.SampleRateMSPSEditField_2.Value = paramsGeneral.paramsGeneral.sampleRate;
            app.StatusLabel.Text = "Status:Axes span set based on config file";

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            title(app.UIAxes2, 'Input Spectrogram -1');
            title(app.UIAxes3, 'Input Spectrogram -2');
            if exist(fullfile(pwd, app.DataFolderEditField_4.Value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end

            app.StatusLabel.Text = "Status:Loading Spectrogram";
            app.nrFilePath = fullfile(pwd, app.DataFolderEditField_4.Value,'NR_frame_0.bb');
            [file,path] = uigetfile(app.nrFilePath, 'Select a baseband(*.bb) file');
            drawnow;
            figure(app.UIFigure);
            if isequal(file,0)
                app.StatusLabel.Text = "Status:No file selected";
                return;
            else
                app.nrFilePath = fullfile(path,file);
                app.Sig1TextArea.Value= file;
            end

            nameParts = split(file, '_');
            if strcmp(nameParts(1), "RADAR")
                app.firstSignal = "RADAR";
                app.StatusLabel.Text = "Status:RADAR signal selected";
            elseif strcmp(nameParts(1), "NR")
                app.firstSignal = "NR";
                app.StatusLabel.Text = "Status:NR signal selected";
            end


            imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
            sr = app.SampleRateMSPSEditField.Value*1e6;     % MHz

            Nfft = 4096;

            bbrNr = comm.BasebandFileReader(app.nrFilePath, SamplesPerFrame=inf);
            data = bbrNr();
            app.inputSpectrogram = helperSpecSenseSpectrogramImage(data,Nfft,sr,imageSize);
            imagesc( app.UIAxes2,app.freqSpan, app.timeSpan, app.inputSpectrogram );


        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            if exist(fullfile(pwd, app.DataFolderEditField_3.Value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            app.rxSpectrogramFilePath = fullfile(pwd, app.DataFolderEditField_3.Value,'RADAR_NR','RADAR_NR_frame_1.png');
            [file,path] = uigetfile(app.rxSpectrogramFilePath, 'Select an image file');
            drawnow;
            figure(app.UIFigure);
            if isequal(file,0)
                app.StatusLabel.Text = "Status:No file selected";
                return;
            else
                app.rxSpectrogramFilePath = fullfile(path,file);
                app.InputSpectrogramTextArea.Value = file;
            end
            app.inputSpectrogram = imread(app.rxSpectrogramFilePath);
            %imshow(app.inputSpectrogram, 'Parent', app.UIAxes);
            S = app.inputSpectrogram;
            imagesc( app.UIAxes,app.freqSpan, app.timeSpan, S );
            [filepath, name, ext] =fileparts(app.rxSpectrogramFilePath);
            label = 'RADAR_NR_frame_';
            nameParts = split(name, '_');
            labelName = strcat(label,nameParts{4});
            labelName = strcat(labelName,'_label');
            labelName = strcat(labelName,'.jpg');
            imLabel = imread(fullfile(filepath, labelName));
            im  = imagesc(app.UIAxes2,app.freqSpan, app.timeSpan,imLabel);
            outputLabel = ["NR" "RADAR" "Noise"];
            numClasses = numel(outputLabel);
            cmap = cool(numClasses);
            im.Parent.Colormap = cmap;

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            title(app.UIAxes2, 'Input Spectrogram -1');
            title(app.UIAxes3, 'Input Spectrogram -2');
            if exist(fullfile(pwd, app.DataFolderEditField_4.Value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end

            app.StatusLabel.Text = "Status:Loading Spectrogram";
            app.radarFilePath = fullfile(pwd, app.DataFolderEditField_4.Value,'RADAR_frame_0.bb');
            [file,path] = uigetfile(app.radarFilePath, 'Select a baseband(*.bb) file');
            drawnow;
            figure(app.UIFigure);
            if isequal(file,0)
                app.StatusLabel.Text = "Status:No file selected";
                return;
            else
                app.radarFilePath = fullfile(path,file);
                app.Sig2TextArea.Value= file;
            end
            nameParts = split(file, '_');
            if strcmp(nameParts(1), "RADAR")
                app.secondSignal = "RADAR";
                app.StatusLabel.Text = "Status:RADAR signal selected";
            elseif strcmp(nameParts(1), "NR")
                app.secondSignal = "NR";
                app.StatusLabel.Text = "Status:NR signal selected";
            end

            imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
            sr = app.SampleRateMSPSEditField.Value*1e6;     % MHz

            Nfft = 4096;

            bbrRadar = comm.BasebandFileReader(app.radarFilePath, SamplesPerFrame=inf);
            data = bbrRadar();
            app.inputSpectrogram = helperSpecSenseSpectrogramImage(data,Nfft,sr,imageSize);

            imagesc( app.UIAxes3,app.freqSpan, app.timeSpan, app.inputSpectrogram );

        end

        % Button pushed function: ClassifyandLabelButton
        function ClassifyandLabelButtonPushed(app, event)
            if app.signalGenerated == false
                app.StatusLabel.Text = "Status:Generate a signal to classify";
                return;
            end
            if app.RadioConnected && app.radioConfigured
                duration = app.FrameDurationmsEditField.Value;
                Nfft = 4096;
                imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
                sr = app.SampleRateMSPSEditField_2.Value*1e6;     % MHz
                app.StatusLabel.Text = "Status:Capturing";
                data = captureUsingRadio(duration);
                stopTransmitRadio();
                app.inputSpectrogram = helperSpecSenseSpectrogramImage(data,Nfft,sr,imageSize);
            end
            app.StatusLabel.Text = "Status:Classifiyng Signals";
            if strcmp(app.currentSection, "NR Only")
                outputLabel = ["NR-1" "NR-2" "Noise"];
            elseif strcmp(app.currentSection, "RADAR Only")
                outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
            else
                outputLabel = ["NR" "RADAR" "Noise"];
            end
            [labeledSpectrogram, predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
            %labeledSpectrogram = app.inputSpectrogram;
            %imshow(labeledSpectrogram, 'Parent', app.UIAxes_2);
            S = labeledSpectrogram;
            imagesc(app.UIAxes_2,app.freqSpan, app.timeSpan, S );
            %  c = colorbar(app.UIAxes_2);
            % c.Label.String="Power Spectral Density (dBFS/Hz)";
            % c.Location="westoutside";
            numClasses = numel(outputLabel);
            cmap = cool(numClasses);
            cmap = [cmap(2,:); cmap(3,:) ;cmap(1,:)];
            im = imagesc(app.UIAxes3,app.freqSpan, app.timeSpan,double(predictedLabels),[1 numClasses]);
            im.Parent.Colormap = cmap;
            app.signalGenerated = false;

        end

        % Button pushed function: GenerateButton_4
        function GenerateButton_4Pushed(app, event)
             if app.radioConfigured == false 
                 app.StatusLabel.Text = "Status: Hardware not configured";   
                 return; 
             end 
           
            if strcmp(app.firstSignal, app.secondSignal)
                if strcmp(app.firstSignal, "NR")
                    app.currentSection = "NR Only";
                else
                    app.currentSection = "RADAR Only";
                end

            else
                if strcmp(app.firstSignal, "RADAR")
                    app.currentSection = "RADAR and NR";
                else
                    app.currentSection = "NR and RADAR";
                end
            end
            app.StatusLabel.Text = sprintf('Status:Generating signal %s', app.currentSection);
            pause(1);
            if isempty(app.nrFilePath)
                app.StatusLabel.Text = "Status:Please browse the first signal";
                return;
            end
            if isempty(app.radarFilePath)
                app.StatusLabel.Text = "Status:Please browse the second signal";
                return;
            end

            app.selectedTab = app.HardwareTestTab;
            imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
            if app.RadioConnected && app.radioConfigured
                SNRdBVec = {100, 100,100};   % dB
            else
                SNRdBVec = {70, 75, 80};   % dB
            end
            Nfft = 4096;
            %duration = 40;
            sr = app.SampleRateMSPSEditField.Value*1e6;     % MHz
            SNRdB = SNRdBVec{randi([1 length(SNRdBVec)])};

            [txSpectrogram, txWave, gTruth, app.sig1Info, app.sig2Info] = SpecSenseNRandRADAR_v1(app.nrFilePath, app.radarFilePath,sr, ...
                SNRdB, imageSize, Nfft,app.currentSection);
            cla(app.UIAxes_2);
            cla(app.UIAxes3);
            title(app.UIAxes2, 'True Labels');
            title(app.UIAxes3, 'Predicted Labels');

            if app.RadioConnected && app.radioConfigured
                transmitUsingRadio(txWave);
                app.StatusLabel.Text = "Status:Transmiting";
            else
                app.inputSpectrogram = txSpectrogram;
            end
            S = txSpectrogram;
            imagesc( app.UIAxes,app.freqSpan, app.timeSpan, S );
            % c = colorbar(app.UIAxes);
            % c.Label.String="Power Spectral Density (dBFS/Hz)";
            % c.Location="westoutside";
            outputLabel = ["NR" "RADAR" "Noise"];
            numClasses = numel(outputLabel);
            cmap = cool(numClasses);
            if strcmp(app.currentSection, "NR Only")
                cmap = [cmap(1,:); cmap(3,:) ;cmap(2,:)];
            end
            if strcmp(app.currentSection, "RADAR and NR")
                cmap = [cmap(1,:); cmap(3,:) ;cmap(2,:)];
            end
            im = imagesc(app.UIAxes2,app.freqSpan, app.timeSpan, gTruth);
            im.Parent.Colormap = cmap;
            app.signalGenerated = true;

        end

        % Callback function
        function PlayButtonPushed(app, event)
            trainDir = app.DataFolderEditField;
            dataDir = fullfile(pwd,trainDir,'RADAR_NR');
            imds = imageDatastore(dataDir,'IncludeSubfolders',false,'FileExtensions','.png');
            app.pauseAutoplay = false;
            app.StatusLabel.Text = "Status:Loading Spectrograms";
            while(imds.hasdata())
                if app.pauseAutoplay
                    break;
                end
                [app.inputSpectrogram,info] = read(imds);
                [filepath, name, ext] =fileparts(info.Filename);
                label = 'RADAR_NR_frame_';
                nameParts = split(name, '_');
                labelName = strcat(label,nameParts{4});
                labelName = strcat(labelName,'_label');
                labelName = strcat(labelName,'.jpg');
                imLabel = imread(fullfile(filepath, labelName));
                app.StatusLabel.Text = "Status:Classifying Signals";
                app.InputSpectrogramTextArea.Value = info.Filename;
                S = app.inputSpectrogram;
                imagesc( app.UIAxes,app.freqSpan, app.timeSpan, S );
                if strcmp(app.currentSection, "NR Only")
                    outputLabel = ["NR-1" "NR-2" "Noise"];
                elseif strcmp(app.currentSection, "RADAR Only")
                    outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
                else
                    outputLabel = ["NR" "RADAR" "Noise"];
                end
                [labeledSpectrogram,predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
                numClasses = numel(outputLabel);
                cmap = cool(numClasses);
                imagesc(app.UIAxes2,app.freqSpan, app.timeSpan,app.inputSpectrogram);
                %im.Parent.Colormap = cmap;
                im = imagesc(app.UIAxes3,app.freqSpan, app.timeSpan,double(predictedLabels),[1 numClasses]);
                im.Parent.Colormap = cmap;

                S = labeledSpectrogram;
                imagesc( app.UIAxes_2,fapp.freqSpan, app.timeSpan, S );
                pause(2);
                app.StatusLabel.Text = "Status:Reading Spectrograms";
            end
        end

        % Callback function
        function PauseButtonPushed(app, event)
            app.pauseAutoplay = true;
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.autoTxStoppped  = true;
            if app.RadioConnected && app.radioConfigured
                stopTransmitRadio();
            end
            app.StatusLabel.Text = "Status:stopped";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.pauseAutoplay = true;
            app.autoTxStoppped = true;
            if(app.selectedTab == app.SoftwareTestTab)
                app.StatusLabel.Text = "Status:";
                title(app.UIAxes, 'Input Spectrogram');
                title(app.UIAxes_2, 'Spectrogram after Signal Classification');
                title(app.UIAxes2, 'True Labels');
                title(app.UIAxes3, 'Predicted Labels');
                xlabel(app.UIAxes, 'Freq(MHz)')
                ylabel(app.UIAxes, 'Time(ms)')
                xlabel(app.UIAxes_2, 'Freq(MHz)')
                ylabel(app.UIAxes_2, 'Time(ms)')

                value = app.DataFolderEditField_3.Value;
                paramsFilePath = fullfile(pwd, value, 'conf.mat');
                if exist(paramsFilePath,'file') == 0
                    app.setAxesSpan( app.defaultRate, app.defaultDuration);
                else
                    paramsGeneral = load(paramsFilePath);
                    app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
                    app.StatusLabel.Text = "Status:Axes span set based on config file";
                end
            elseif (app.selectedTab == app.GenerateTrainingDataTab)
                app.StatusLabel.Text = "Status:";
                title(app.UIAxes, 'NR Spectrogram (For Training)');
                title(app.UIAxes_2, 'RADAR Spectrogram (For Training)');
                title(app.UIAxes2, 'Combined Spectrogram (For Testing)');
                title(app.UIAxes3, 'True Labels');
                xlabel(app.UIAxes, 'Freq(MHz)')
                ylabel(app.UIAxes, 'Time(ms)')
                xlabel(app.UIAxes_2, 'Freq(MHz)')
                ylabel(app.UIAxes_2, 'Time(ms)')
                app.setAxesSpan( app.SampleRateMSPSEditField.Value, app.FrameDurationmsEditField.Value);
            elseif(app.selectedTab == app.TrainandEvaluateTab)
                app.StatusLabel.Text = "Status:";
                title(app.UIAxes2, 'Input Spectrogram');
                title(app.UIAxes3, 'Spectrogram after Signal Classification');
                title(app.UIAxes, 'IOU');
                title(app.UIAxes_2, 'Confusion Matrix');
                value = app.DataFolderEditField_2.Value;
                paramsFilePath = fullfile(pwd, value, 'conf.mat');
                if exist(paramsFilePath,'file') == 0
                    app.setAxesSpan( app.defaultRate, app.defaultDuration);
                else
                    paramsGeneral = load(paramsFilePath);
                    app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
                    app.StatusLabel.Text = "Status:Axes span set based on config file";
                end
            elseif(app.selectedTab == app.HardwareTestTab)
                app.StatusLabel.Text = "Status:";
                title(app.UIAxes, 'Input Spectrogram');
                title(app.UIAxes_2, 'Spectrogram after Signal Classification');
                title(app.UIAxes2, 'Input Spectrogram -1');
                title(app.UIAxes3, 'Input Spectrogram -2');
                xlabel(app.UIAxes, 'Freq(MHz)')
                ylabel(app.UIAxes, 'Time(ms)')
                xlabel(app.UIAxes_2, 'Freq(MHz)')
                ylabel(app.UIAxes_2, 'Time(ms)')

                value = app.DataFolderEditField_4.Value;
                paramsFilePath = fullfile(pwd, value, 'conf.mat');
                if exist(paramsFilePath,'file') == 0
                    app.setAxesSpan( app.defaultRate, app.defaultDuration);
                else
                    paramsGeneral = load(paramsFilePath);
                    app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
                    app.StatusLabel.Text = "Status:Axes span set based on config file";
                end

                if app.RadioConnected && app.radioConfigured
                    sr = app.SampleRateMSPSEditField_2.Value;
                    fc =app.CenterFreqGHzEditField.Value;
                    gain = app.RxGaindBEditField.Value;
                    txgain = app.TxGaindBEditField.Value;
                    ret = InitSpecSense(sr,fc,gain,txgain);
                    if ret == 1
                        app.StatusLabel.Text = "Status:Initialization Success";
                    else
                        app.StatusLabel.Text = "Status:Initialization Failed";
                    end
                else
                    app.StatusLabel.Text = "Status:Check if hardware connected";
                end


            end


        end

        % Callback function
        function PlayButton_2Pushed(app, event)

            imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
            SNRdBVec = {100, 100, 100};   % dB
            Nfft = 4096;
            sampleRate = app.SampleRateMSPSEditField.Value*1e6;
            duration = app.FrameDurationmsEditField.Value;
            app.autoTxStoppped = false;
            while ~app.autoTxStoppped

                SNRdB = SNRdBVec{randi([1 length(SNRdBVec)])};

                [txSpectrogram, txWave, gTruth] = SpecSenseNRandRADAR(app.nrFilePath, app.radarFilePath,sampleRate, ...
                    SNRdB, imageSize, Nfft);
                S = txSpectrogram;
                imagesc( app.UIAxes,app.freqSpan, app.timeSpan, S );
                cla(app.UIAxes_2);
                cla(app.UIAxes3);
                cla(app.UIAxes2);

                if app.RadioConnected && app.radioConfigured
                    transmitUsingRadio(txWave);
                    app.StatusLabel.Text = "Status:Transmitting";
                    pause(1);
                    app.StatusLabel.Text = "Status:Capturing";
                    data = captureUsingRadio(duration);
                    %data = loopbackRadio(txWave , duration);
                    app.inputSpectrogram = helperSpecSenseSpectrogramImage(data,Nfft,sr,imageSize);
                    %S = app.inputSpectrogram;
                end
                app.StatusLabel.Text = "Status:Classifying";
                if strcmp(app.currentSection, "NR Only")
                    outputLabel = ["NR-1" "NR-2" "Noise"];
                elseif strcmp(app.currentSection, "RADAR Only")
                    outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
                else
                    outputLabel = ["NR" "RADAR" "Noise"];
                end
                [labeledSpectrogram, predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
                %labeledSpectrogram = app.inputSpectrogram;
                %S = labeledSpectrogram;
                imagesc( app.UIAxes_2,app.freqSpan, app.timeSpan, labeledSpectrogram );
                numClasses = numel(outputLabel);
                cmap = cool(numClasses);
                im = imagesc(app.UIAxes2,app.freqSpan, app.timeSpan,gTruth);
                im.Parent.Colormap = cmap;
                im = imagesc(app.UIAxes3,app.freqSpan, app.timeSpan,double(predictedLabels),[1 numClasses]);
                im.Parent.Colormap = cmap;
                pause(1);
            end

            app.StatusLabel.Text = "Status:Auto play stopped";


        end

        % Button pushed function: ClassifyButton_2
        function ClassifyButton_2Pushed(app, event)
            if isempty(app.inputSpectrogram)
                app.StatusLabel.Text = "Status:Please browse a spectrogram";
                return;
            end
            app.StatusLabel.Text = "Status:Classifiyng Signals";
            if strcmp(app.currentSection, "NR Only")
                outputLabel = ["NR-1" "NR-2" "Noise"];
            elseif strcmp(app.currentSection, "RADAR Only")
                outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
            else
                outputLabel = ["NR" "RADAR" "Noise"];
            end
            [labeledSpectrogram, predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
            %labeledSpectrogram = app.inputSpectrogram;
            %             [filepath, name, ext] =fileparts(app.rxSpectrogramFilePath);
            %             label = 'RADAR_NR_frame_';
            %             nameParts = split(name, '_');
            %             labelName = strcat(label,nameParts{4});
            %             labelName = strcat(labelName,'_label');
            %             labelName = strcat(labelName,'.jpg');


            S = labeledSpectrogram;
            imagesc( app.UIAxes_2,app.freqSpan,app.timeSpan, S );
            numClasses = numel(outputLabel);
            cmap = cool(numClasses);
            cmap = [cmap(2,:); cmap(3,:) ;cmap(1,:)];
            im = imagesc(app.UIAxes3,app.freqSpan,app.timeSpan,double(predictedLabels),[1 numClasses]);
            im.Parent.Colormap = cmap;

        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            items  = ["RADAR and NR", "NR Only", "RADAR Only"];
            value = app.SignalTypeDropDown.Value;
            app.currentSection = string(value);
            index = find(ismember(items,app.currentSection));
            switch index
                case 1
                    app.StatusLabel.Text =  string(value);
                    app.Sig1TextAreaLabel.Text = 'NR';
                    app.Sig1TextArea.Value = 'nr_0.bb';
                    app.Sig2TextAreaLabel.Text = 'RADAR';
                    app.Sig2TextArea.Value = 'radar_0.bb';
                    %                     app.UIAxes2.Title.String = 'NR';
                    %                     app.UIAxes3.Title.String = 'RADAR';
                case 2
                    app.StatusLabel.Text =  string(value);
                    app.Sig1TextAreaLabel.Text = 'NR-1';
                    app.Sig1TextArea.Value = 'nr_0.bb';
                    app.Sig2TextAreaLabel.Text = 'NR-2';
                    app.Sig2TextArea.Value = 'nr_1.bb';
                    %                     app.UIAxes2.Title.String = 'NR-1';
                    %                     app.UIAxes3.Title.String = 'NR-2';
                case 3
                    app.StatusLabel.Text =  string(value);
                    app.Sig1TextAreaLabel.Text = 'RADAR-1';
                    app.Sig1TextArea.Value = 'radar_0.bb';
                    app.Sig2TextAreaLabel.Text = 'RADAR-2';
                    app.Sig2TextArea.Value = 'radar_1.bb';
                    %                     app.UIAxes2.Title.String = 'RADAR-1';
                    %                     app.UIAxes3.Title.String = 'RADAR-2';

            end


        end

        % Value changed function: USRPPresentDropDown
        function USRPPresentDropDownValueChanged(app, event)
            value = app.USRPPresentDropDown.Value;
            if strcmp(value, "Yes")
                app.RadioConnected = true;
                app.GenerateButton_4.Text = 'Generate and Transmit';
                app.ClassifyandLabelButton.Text = 'Capture, Classify, and Label';
                app.ConfigureHWButton.Enable = "on";
            else
                app.RadioConnected = false;
                app.GenerateButton_4.Text = 'Generate';
                app.ClassifyandLabelButton.Text = 'Classify and Label';
                app.ConfigureHWButton.Enable = "off";
            end

        end

        % Button pushed function: ConfigureHWButton
        function ConfigureHWButtonPushed(app, event)
            sr = app.SampleRateMSPSEditField_2.Value;
            fc =app.CenterFreqGHzEditField.Value;
            rxgain = app.RxGaindBEditField.Value;
            txgain = app.TxGaindBEditField.Value;
            ret = InitSpecSense(sr,fc,rxgain, txgain);
            if ret == 1
                app.StatusLabel.Text = "Status:Initialization Success";
                app.radioConfigured = true;
            else
                app.StatusLabel.Text = "Status:Initialization Failed";
                app.radioConfigured = false;
            end
        end

        % Callback function
        function PlayPauseCheckBoxValueChanged(app, event)
            value = app.PlayPauseCheckBox.Value;
            if value
                trainDir = ['C:\Users\vijayenk\OneDrive - MathWorks\Documents\MATLAB\Examples\' ...
                    'R2023b\deeplearning_shared\SpectrumSensingWithDeepLearning5GLTEExample\N320TrainingData010223\'];
                dataDir = fullfile(trainDir,'RADAR_NR');
                imds = imageDatastore(dataDir,'IncludeSubfolders',false,'FileExtensions','.png');
                app.pauseAutoplay = false;
                app.StatusLabel.Text = "Status:Loading Spectrograms";
                while(imds.hasdata())
                    if app.pauseAutoplay
                        break;
                    end
                    [app.inputSpectrogram,info] = read(imds);
                    [filepath, name, ext] =fileparts(info.Filename);
                    label = 'RADAR_NR_frame_';
                    nameParts = split(name, '_');
                    labelName = strcat(label,nameParts{4});
                    labelName = strcat(labelName,'_label');
                    labelName = strcat(labelName,'.jpg');
                    imLabel = imread(fullfile(filepath, labelName));
                    app.StatusLabel.Text = "Status:Classifying Signals";
                    app.InputSpectrogramTextArea.Value = info.Filename;
                    S = app.inputSpectrogram;


                    imagesc( app.UIAxes,app.freqSpan,app.timeSpan, S );
                    if strcmp(app.currentSection, "NR Only")
                        outputLabel = ["NR-1" "NR-2" "Noise"];
                    elseif strcmp(app.currentSection, "RADAR Only")
                        outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
                    else
                        outputLabel = ["NR" "RADAR" "Noise"];
                    end
                    [labeledSpectrogram,predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
                    numClasses = numel(outputLabel);
                    cmap1 = cool(numClasses);
                    cmap2 = cool(numClasses);
                    cmap2 = [cmap2(2,:); cmap2(3,:) ;cmap2(1,:)];
                    %cmap2 = gray(256);
                    %outputLabel = ["Noise" "NR" "RADAR"];
                    %binnedData = discretize(imLabel,[0 20 150 255],'categorical',outputLabel);
                    %im = imagesc(app.UIAxes2,freq,time,double(binnedData),[1 numClasses]);
                    %im.Parent.Colormap = cmap;
                    im = imagesc(app.UIAxes2,freq,time,imLabel);
                    im.Parent.Colormap = cmap1;
                    im = imagesc(app.UIAxes3,freq,time,double(predictedLabels),[1 numClasses]);
                    im.Parent.Colormap = cmap2;

                    S = labeledSpectrogram;
                    imagesc( app.UIAxes_2,app.freqSpan,app.timeSpan, S );
                    pause(2);
                    app.StatusLabel.Text = "Status:Reading Spectrograms";
                end
            else
                app.pauseAutoplay = true;
                app.StatusLabel.Text = "Status:";
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if strcmp(app.currentSection,"NR Only")
                title = "NR Info";
            elseif strcmp(app.currentSection,"RADAR Only")
                title = "RADAR Info";
            else
                title = "NR Info";
            end
            if app.signalGenerated
                f = msgbox(app.sig1Info,title);
            else
                if app.RadioConnected && app.radioConfigured
                    f = msgbox("Browse a baseband file and press Generate and Transmit to view the signal information",title);
                else
                    f = msgbox("Browse a baseband file and press Generate to view the signal information",title);
                end
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            if strcmp(app.currentSection,"NR Only")
                title = "NR Info";
            elseif strcmp(app.currentSection,"RADAR Only")
                title = "RADAR Info";
            else
                title = "RADAR Info";
            end
            if app.signalGenerated
                f = msgbox(app.sig2Info,title);
            else
                if app.RadioConnected && app.radioConfigured
                    f = msgbox("Browse a baseband file and press Generate and Transmit to view the signal information",title);
                else
                    f = msgbox("Browse a baseband file and press Generate to view the signal information",title);
                end
            end
        end

        % Value changed function: CollectSignalsButton
        function CollectSignalsButtonValueChanged(app, event)
            value = app.CollectSignalsButton.Value;

            if value
                app.generationAbort = false;
                app.CollectSignalsButton.Text = "Abort";
                if app.radioConfigured == false && strcmp (app.USRPPresentDropDown_2.Value, 'Yes')
                 app.StatusLabel.Text = "Status: Hardware not configured";   
                 return; 
                end 
               
                
                paramsNR = struct();
                paramsGeneral = struct();
                paramsRADAR = struct();

                paramsGeneral.dataFolder = app.DataFolderEditField.Value;
                paramsGeneral.frameDuration = app.FrameDurationmsEditField.Value;
                paramsGeneral.sampleRate = app.SampleRateMSPSEditField.Value;
                paramsGeneral.numOfFrames = app.NoofFramesEditField.Value;
                paramsGeneral.centerFreq = app.CenterFreqGHzEditField_2.Value;
                paramsGeneral.rxgain = app.RxGaindBEditField_2.Value;
                paramsGeneral.txgain = app.TxGaindBEditField_2.Value;
                paramsGeneral.imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
                combinedDir = fullfile(pwd, paramsGeneral.dataFolder);
                if ~exist(combinedDir,'dir')
                    mkdir(combinedDir)
                end
                fname = "conf.mat";
                fname = fullfile(pwd, paramsGeneral.dataFolder,fname);
                save(fname, "paramsGeneral");

                paramsNR.sigType =app.DownlinkUplinkDropDown.Value;
                paramsNR.SCS = str2double(split(app.SubcarrierspacingKHzEditField.Value));
                paramsNR.bandwidth = str2double(split(app.BandwidthMHzEditField_2.Value));
                paramsNR.ssbPeriod = str2double(split(app.SSBPeriodmsEditField.Value));
                paramsNR.doppler = str2double(split(app.DopplerHzEditField.Value));

                paramsRADAR.modulation = app.ModulationDropDown.Value;
                paramsRADAR.bandwidthRange= str2double(split(app.BandwidthrangeinMHzEditField.Value));
                paramsRADAR.sweepDirection  = convertCharsToStrings(split(app.SweepDirectionEditField.Value));
                paramsRADAR.PRFPulseDurationProduct  = str2double(split(app.PRFxPulseDurationEditField.Value));
                paramsRADAR.sweepInterval = app.SweepIntervalDropDown.Value;

                outputLabel = ["NR" "RADAR" "Noise"];
                numClasses = numel(outputLabel);
                cmap = cool(numClasses);
                startIdx = app.StartIdxEditField.Value;
                for i= startIdx:paramsGeneral.numOfFrames
                    if app.generationAbort
                        app.CollectSignalsButton.Value = false;
                        app.CollectSignalsButton.Text = "Collect Signals";

                        break;
                    end
                    [nrImg, radarImg, combImg, combTruth] =helperSpecSenseTrainingDataNRAndRADARSingleThread_v1(paramsGeneral,paramsNR, ...
                        paramsRADAR,i,app.USRPPresentDropDown_2.Value, app.RawSignalStoreIntervalEditField.Value);

                    imagesc( app.UIAxes,app.freqSpan,app.timeSpan, nrImg);
                    imagesc( app.UIAxes_2,app.freqSpan,app.timeSpan, radarImg);
                    imagesc( app.UIAxes2,app.freqSpan,app.timeSpan, combImg);
                    im =imagesc( app.UIAxes3,app.freqSpan,app.timeSpan, combTruth, [1 numClasses]);

                    im.Parent.Colormap = cmap;

                    formatSpec = "Status:Generated signal %d of %d";
                    app.StatusLabel.Text= sprintf(formatSpec,i, paramsGeneral.numOfFrames);
                    pause(1);
                    if i == paramsGeneral.numOfFrames
                        app.CollectSignalsButton.Value = false;
                        app.CollectSignalsButton.Text = "Collect Signals";
                        app.StatusLabel.Text = "Status:Signal generation complete";

                    end

                end
            else
                app.CollectSignalsButton.Text = "Collect Signals";
                app.generationAbort = true;
                app.StatusLabel.Text = "Status:Signal generation aborted";
            end


        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if exist(fullfile(pwd, app.trainingDataFolder),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            paramsGeneral = struct();
            paramsNetwork = struct();
            paramsGeneral.dataFolder = app.trainingDataFolder;
            paramsGeneral.baseNetwork = app.BaseNetworkDropDown.Value;
            paramsGeneral.classes  = convertCharsToStrings(split(app.ClassNamesEditField.Value));
            paramsGeneral.networkName = convertStringsToChars(app.NetworkNameEditField.Value);
            paramsGeneral.imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));

            paramsNetwork.solverName  = convertCharsToStrings(app.SolverNameDropDown.Value);
            paramsNetwork.plots  =app.PlotsDropDown.Value;
            paramsNetwork.maxEpochs = str2double(app.MaxEpochsEditField.Value);
            paramsNetwork.InitialLearnRate = str2double(app.InitialLearningRateEditField.Value);
            paramsNetwork.executionEnvironment  = app.ExecutionEnvironmentDropDown.Value;
            trainNetworkForNRandRADAR(paramsGeneral,paramsNetwork);
        end

        % Callback function
        function EvaluateButtonValueChanged(app, event)

        end

        % Button pushed function: LoadNetworkButton_2
        function LoadNetworkButton_2Pushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select a trained network(*.mat) file');
           drawnow;
            figure(app.UIFigure);
            if file == 0
                app.StatusLabel.Text = "Status:No trained network selected";
                return;
            end
            app.NetworkEditField.Value = file;
            ret = loadTrainedNetwork(app.NetworkEditField.Value);
            if ret == 1
                app.StatusLabel.Text = "Status:AI loading Success";
            else
                app.StatusLabel.Text = "Status:AI Loading Failed";
            end
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select a trained network(*.mat) file');
            drawnow;
            figure(app.UIFigure);
            if file == 0
                app.StatusLabel.Text = "Status:No trained network selected";
                return;
            end
            app.NetworkEditField_2.Value = file;
            ret = loadTrainedNetwork(app.NetworkEditField_2.Value);
            if ret == 1
                app.StatusLabel.Text = "Status:AI loading Success";
            else
                app.StatusLabel.Text = "Status:AI Loading Failed";
            end

        end

        % Value changed function: LoopButton_2
        function LoopButton_2ValueChanged(app, event)
            value = app.LoopButton_2.Value;
            if value
                trainDir = app.DataFolderEditField_3.Value;
                if isempty(trainDir)
                    app.StatusLabel.Text = "Status:Select the data folder";
                    return;
                end 
                if isempty(app.NetworkEditField_2.Value)
                    app.StatusLabel.Text = "Status:Select the trained network";
                    return;
                end  
                dataDir = fullfile(pwd,trainDir,'RADAR_NR');
                imds = imageDatastore(dataDir,'IncludeSubfolders',false,'FileExtensions','.png');
                app.pauseAutoplay = false;
                app.StatusLabel.Text = "Status:Loading Spectrograms";
                app.LoopButton_2.Text  = 'Pause';
                while(imds.hasdata())
                    if app.pauseAutoplay
                        app.LoopButton_2.Text  = 'Loop';
                        app.LoopButton_2.Value = false;

                        break;
                    end
                    [app.inputSpectrogram,info] = read(imds);
                    [filepath, name, ext] =fileparts(info.Filename);
                    label = 'RADAR_NR_frame_';
                    nameParts = split(name, '_');
                    labelName = strcat(label,nameParts{4});
                    labelName = strcat(labelName,'_label');
                    labelName = strcat(labelName,'.jpg');
                    imLabel = imread(fullfile(filepath, labelName));
                    app.StatusLabel.Text = "Status:Classifying Signals";
                    app.InputSpectrogramTextArea.Value = sprintf('%s%s',name,ext);
                    S = app.inputSpectrogram;

                    imagesc( app.UIAxes,app.freqSpan,app.timeSpan, S );
                    if strcmp(app.currentSection, "NR Only")
                        outputLabel = ["NR-1" "NR-2" "Noise"];
                    elseif strcmp(app.currentSection, "RADAR Only")
                        outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
                    else
                        outputLabel = ["NR" "RADAR" "Noise"];
                    end
                    [labeledSpectrogram,predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
                    numClasses = numel(outputLabel);
                    cmap1 = cool(numClasses);
                    cmap2 = cool(numClasses);
                    cmap2 = [cmap2(2,:); cmap2(3,:) ;cmap2(1,:)];
                    %cmap2 = gray(256);
                    %outputLabel = ["Noise" "NR" "RADAR"];
                    %binnedData = discretize(imLabel,[0 20 150 255],'categorical',outputLabel);
                    %im = imagesc(app.UIAxes2,freq,time,double(binnedData),[1 numClasses]);
                    %im.Parent.Colormap = cmap;
                    im = imagesc(app.UIAxes2,app.freqSpan,app.timeSpan,imLabel);
                    im.Parent.Colormap = cmap1;
                    im = imagesc(app.UIAxes3,app.freqSpan,app.timeSpan,double(predictedLabels),[1 numClasses]);
                    im.Parent.Colormap = cmap2;

                    S = labeledSpectrogram;
                    imagesc( app.UIAxes_2,app.freqSpan,app.timeSpan, S );
                    pause(2);
                    app.StatusLabel.Text = "Status:Reading Spectrograms";
                end
                if imds.hasdata() == false
                    app.pauseAutoplay = true;
                    app.StatusLabel.Text = "Status:";
                    app.LoopButton_2.Text  = 'Loop';
                    app.LoopButton_2.Value = false;
                end
            else
                app.pauseAutoplay = true;
                app.StatusLabel.Text = "Status:";
                app.LoopButton_2.Text  = 'Loop';
                app.LoopButton_2.Value = false;
            end


        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            if exist(fullfile(pwd, app.trainingDataFolder),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end

            app.StatusLabel.Text = "Status:Network evaluation started";
            paramsGeneral = struct();
            paramsEvaluate = struct();
            paramsGeneral.dataFolder = app.trainingDataFolder;
            paramsGeneral.baseNetwork = app.BaseNetworkDropDown.Value;
            paramsGeneral.classes  = convertCharsToStrings(split(app.ClassNamesEditField.Value));
            paramsGeneral.networkName = convertStringsToChars(app.NetworkNameEditField.Value);
            paramsGeneral.imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));

            paramsEvaluate.evaluationFolder = app.EvaluationFolderEditField.Value;


            [input, results,hist, confusion]=evaluateNetworkForNRandRADAR(paramsGeneral, paramsEvaluate);
            app.StatusLabel.Text = "Status:Network evaluation completed";


            imagesc(app.UIAxes2,app.freqSpan,app.timeSpan, input);
            imagesc(app.UIAxes3,app.freqSpan,app.timeSpan, results );
            imagesc( app.UIAxes,[0 1], [0 1], hist);
            imagesc( app.UIAxes_2,[0 1], [0 1], confusion);


        end

        % Button pushed function: LoadNetworkButton_3
        function LoadNetworkButton_3Pushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select a trained network(*.mat) file');
            drawnow;
            figure(app.UIFigure);
            if file == 0
                app.StatusLabel.Text = "Status:No trained network selected";
                return;
            end
            app.NetworkEditField.Value = file;
            app.NetworkNameEditField.Value =file;
            ret = loadTrainedNetwork(app.NetworkEditField.Value);
            if ret == 1
                app.StatusLabel.Text = "Status:AI loading Success";
            else
                app.StatusLabel.Text = "Status:AI Loading Failed";
            end
        end

        % Value changed function: USRPDropDown
        function USRPDropDownValueChanged(app, event)
            value = app.USRPDropDown.Value;
            str_f = sprintf('Status:%s selected',value);
            app.StatusLabel.Text = str_f;
            if strcmp(value, 'N320')
                app.SampleRateMSPSEditField.Value = 245.76;
                app.TxGaindBEditField_2.Value = 60;
            elseif strcmp(value, 'N321')
                app.SampleRateMSPSEditField.Value = 245.76;
                app.TxGaindBEditField_2.Value = 60;
            elseif strcmp(value, 'N310')
                app.SampleRateMSPSEditField.Value = 153.6;
                app.TxGaindBEditField_2.Value = 75;
            elseif strcmp(value, 'X310 with UBX')
                app.SampleRateMSPSEditField.Value = 184.32;
                app.TxGaindBEditField_2.Value = 31.5;
            elseif strcmp(value, 'X410')
                app.SampleRateMSPSEditField.Value = 245.76;
                app.TxGaindBEditField_2.Value = 60;
            end
            app.setAxesSpan(app.SampleRateMSPSEditField.Value, app.FrameDurationmsEditField.Value);
        end

        % Value changed function: InputSpectrogramTextArea
        function InputSpectrogramTextAreaValueChanged(app, event)
            value = app.InputSpectrogramTextArea.Value;


        end

        % Value changed function: DataFolderEditField_3
        function DataFolderEditField_3ValueChanged(app, event)
            value = app.DataFolderEditField_3.Value;
            if exist(fullfile(pwd, value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            paramsFilePath = fullfile(pwd, value, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Value changed function: DataFolderEditField_2
        function DataFolderEditField_2ValueChanged(app, event)
            value = app.DataFolderEditField_2.Value;
            if exist(fullfile(pwd, value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            paramsFilePath = fullfile(pwd, value, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Value changed function: DataFolderEditField_4
        function DataFolderEditField_4ValueChanged(app, event)
            value = app.DataFolderEditField_4.Value;
            if exist(fullfile(pwd, value),'dir') == 0
                app.StatusLabel.Text = "Status:Data Folder does not exist";
                return;
            end
            paramsFilePath = fullfile(pwd, value, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                app.SampleRateMSPSEditField_2.Value = app.defaultRate;
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.SampleRateMSPSEditField_2.Value = paramsGeneral.paramsGeneral.sampleRate;
            app.CenterFreqGHzEditField.Value = paramsGeneral.paramsGeneral.centerFreq;
            app.TxGaindBEditField.Value = paramsGeneral.paramsGeneral.txgain;
            app.RxGaindBEditField.Value = paramsGeneral.paramsGeneral.rxgain;
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            selpath = uigetdir(pwd,'Select Data Folder');
            drawnow;
            figure(app.UIFigure);
            if selpath == 0
                app.StatusLabel.Text = "Status:Select data folder for training";
                return;
            end
            [filepath,name,ext] = fileparts(selpath);
            app.trainingDataFolder = name;
            app.DataFolderEditField_2.Value = name;
            paramsFilePath = fullfile(selpath, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            selpath = uigetdir(pwd,'Select Data Folder');
            drawnow;
            figure(app.UIFigure);
            if selpath == 0
                app.StatusLabel.Text = "Status:Select data folder for test";
                return;
            end
            [filepath,name,ext] = fileparts(selpath);
            app.trainingDataFolder = name;
            app.DataFolderEditField_3.Value = name;
            paramsFilePath = fullfile(selpath, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            selpath = uigetdir(pwd,'Select Data Folder');
            drawnow;
            figure(app.UIFigure);
            if selpath ==0
                app.StatusLabel.Text = "Status:Generate data for training and test";
                return;
            end
            [filepath,name,ext] = fileparts(selpath);
            app.trainingDataFolder = name;
            app.DataFolderEditField.Value = name;
            paramsFilePath = fullfile(selpath, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            selpath = uigetdir(pwd,'Select Data Folder');
            drawnow;
            figure(app.UIFigure);
            if selpath == 0
                app.StatusLabel.Text = "Status:Select data folder for test";
                return;
            end
            [filepath,name,ext] = fileparts(selpath);
            app.trainingDataFolder = name;
            app.DataFolderEditField_4.Value = name;
            paramsFilePath = fullfile(selpath, 'conf.mat');
            if exist(paramsFilePath,'file') == 0
                app.StatusLabel.Text = "Status:Configuration file doesn't exist";
                app.setAxesSpan( app.defaultRate,  app.defaultDuration);
                return;
            end
            paramsGeneral = load(paramsFilePath);
            app.SampleRateMSPSEditField_2.Value = paramsGeneral.paramsGeneral.sampleRate;
            app.CenterFreqGHzEditField.Value = paramsGeneral.paramsGeneral.centerFreq;
            app.TxGaindBEditField.Value = paramsGeneral.paramsGeneral.txgain;
            app.RxGaindBEditField.Value = paramsGeneral.paramsGeneral.rxgain;
            app.setAxesSpan( paramsGeneral.paramsGeneral.sampleRate,  paramsGeneral.paramsGeneral.frameDuration);
            app.StatusLabel.Text = "Status:Axes span set based on config file";
        end

        % Callback function
        function Button_5Pushed(app, event)
            [file,path] = uigetfile('*.mat', 'Select a trained network(*.mat) file');
            app.NetworkNameEditField.Value  = file;
        end

        % Value changed function: LoopButton
        function LoopButtonValueChanged(app, event)
            value = app.LoopButton.Value;
            title(app.UIAxes2, 'True Labels');
            title(app.UIAxes3, 'Predicted Labels');
            if value
                trainDir = app.DataFolderEditField_4.Value;
                if isempty(trainDir)
                    app.StatusLabel.Text = "Status:Select the data folder";
                    return;
                end 
                if isempty(app.NetworkEditField.Value)
                    app.StatusLabel.Text = "Status:Select the trained network";
                    return;
                end    
                fileList = dir(fullfile(pwd,trainDir, '*.bb'));
                app.pauseAutoplay = false;
                
                app.LoopButton.Text  = 'Pause';
                while(~app.pauseAutoplay)
                    if app.RadioConnected && app.radioConfigured
                        SNRdBVec = {100, 100,100};   % dB
                    else
                        SNRdBVec = {70, 75, 80};   % dB
                    end
                    Nfft = 4096;
                    %duration = 40;
                    sr = app.SampleRateMSPSEditField_2.Value*1e6;     % MHz
                    SNRdB = SNRdBVec{randi([1 length(SNRdBVec)])};
                    idx1 = randi([1 length(fileList)]);
                    idx2 = randi([1 length(fileList)]);
                    app.nrFilePath = fullfile(fileList(idx1).folder,fileList(idx1).name);
                    app.radarFilePath = fullfile(fileList(idx2).folder,fileList(idx2).name);
                    nameParts = split(fileList(idx1).name, '_');
                    if strcmp(nameParts(1), "RADAR")
                        app.firstSignal = "RADAR";
                    elseif strcmp(nameParts(1), "NR")
                        app.firstSignal = "NR";
                    end
                    nameParts = split(fileList(idx2).name, '_');
                    if strcmp(nameParts(1), "RADAR")
                        app.secondSignal = "RADAR";
                    elseif strcmp(nameParts(1), "NR")
                        app.secondSignal = "NR";
                    end
                    if strcmp(app.firstSignal, app.secondSignal)
                        if strcmp(app.firstSignal, "NR")
                            app.currentSection = "NR Only";
                        else
                            app.currentSection = "RADAR Only";
                        end

                    else
                        if strcmp(app.firstSignal, "RADAR")
                            app.currentSection = "RADAR and NR";
                        else
                            app.currentSection = "NR and RADAR";
                        end
                    end
                    app.StatusLabel.Text = sprintf('Status:Generating signal %s', app.currentSection);
                    pause(1);
                    imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
                    [txSpectrogram, txWave, gTruth, app.sig1Info, app.sig2Info] = SpecSenseNRandRADAR_v1(app.nrFilePath, app.radarFilePath,sr, ...
                        SNRdB, imageSize, Nfft,app.currentSection);

                    
                    app.Sig1TextArea.Value = fileList(idx1).name;
                    app.Sig2TextArea.Value = fileList(idx2).name;
                    S = txSpectrogram;

                    imagesc( app.UIAxes,app.freqSpan,app.timeSpan, S );
                    if strcmp(app.currentSection, "NR Only")
                        outputLabel = ["NR-1" "NR-2" "Noise"];
                    elseif strcmp(app.currentSection, "RADAR Only")
                        outputLabel = ["RADAR-1" "RADAR-2" "Noise"];
                    else
                        outputLabel = ["NR" "RADAR" "Noise"];
                    end
                    numClasses = numel(outputLabel);
                    cmap = cool(numClasses);
                    if strcmp(app.currentSection, "NR Only")
                        cmap = [cmap(1,:); cmap(3,:) ;cmap(2,:)];
                    end
                    if strcmp(app.currentSection, "RADAR and NR")
                        cmap = [cmap(1,:); cmap(3,:) ;cmap(2,:)];
                    end
                    im = imagesc(app.UIAxes2,app.freqSpan, app.timeSpan, gTruth);
                    im.Parent.Colormap = cmap;
                    app.signalGenerated = true;
                    if app.RadioConnected && app.radioConfigured
                        transmitUsingRadio(txWave);
                        app.StatusLabel.Text = "Status:Transmiting";
                        cla(app.UIAxes3);
                        cla(app.UIAxes_2);
                        pause(.1);
                        duration = app.FrameDurationmsEditField.Value;
                        Nfft = 4096;
                        imageSize = str2double(split(app.SpectrogramSizePixelsEditField.Value));
                        sr = app.SampleRateMSPSEditField_2.Value*1e6;     % MHz
                        app.StatusLabel.Text = "Status:Capturing";
                        data = captureUsingRadio(duration);
                        stopTransmitRadio();
                        app.inputSpectrogram = helperSpecSenseSpectrogramImage(data,Nfft,sr,imageSize);
                    else
                        app.inputSpectrogram = txSpectrogram;
                    end
                    app.StatusLabel.Text = "Status:Classifying Signals";
                    
                    [labeledSpectrogram,predictedLabels] = classifyNRandRADARInSpectrogram(app.inputSpectrogram,outputLabel);
                    numClasses = numel(outputLabel);
                    cmap2 = cool(numClasses);
                    cmap2 = [cmap2(2,:); cmap2(3,:) ;cmap2(1,:)];
                    im = imagesc(app.UIAxes3,app.freqSpan,app.timeSpan,double(predictedLabels),[1 numClasses]);
                    im.Parent.Colormap = cmap2;

                    S = labeledSpectrogram;
                    imagesc( app.UIAxes_2,app.freqSpan,app.timeSpan, S );
                    pause(2);

                end
                app.pauseAutoplay = true;
                app.StatusLabel.Text = "Status:";
                app.LoopButton.Text  = 'Loop';
                app.LoopButton.Value = false;
            else
                app.pauseAutoplay = true;
                app.StatusLabel.Text = "Status:";
                app.LoopButton.Text  = 'Loop';
                app.LoopButton.Value = false;
            end

        end

        % Button pushed function: ConfigureHWButton_2
        function ConfigureHWButton_2Pushed(app, event)
             sr = app.SampleRateMSPSEditField.Value;
            fc =app.CenterFreqGHzEditField_2.Value;
            rxgain = app.RxGaindBEditField_2.Value;
            txgain = app.TxGaindBEditField_2.Value;
            ret = InitSpecSense(sr,fc,rxgain, txgain);
            if ret == 1
                app.StatusLabel.Text = "Status:Initialization Success";
                app.radioConfigured = true;
            else
                app.StatusLabel.Text = "Status:Initialization Failed";
                app.radioConfigured = false;
            end
        end

        % Value changed function: USRPPresentDropDown_2
        function USRPPresentDropDown_2ValueChanged(app, event)
            value = app.USRPPresentDropDown_2.Value;
             if strcmp(value, "Yes")
                app.ConfigureHWButton_2.Enable = "on";
             else
                app.ConfigureHWButton_2.Enable = "off";
             end
        end

        % Value changed function: SampleRateMSPSEditField
        function SampleRateMSPSEditFieldValueChanged(app, event)
            value = app.SampleRateMSPSEditField.Value;
            app.setAxesSpan( value,  app.defaultDuration);
            
        end

        % Value changed function: FrameDurationmsEditField
        function FrameDurationmsEditFieldValueChanged(app, event)
            value = app.FrameDurationmsEditField.Value;
            app.setAxesSpan( app.defaultRate,  value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'NR Spectrogram ( For Training)')
            xlabel(app.UIAxes, 'Freq(MHz)')
            ylabel(app.UIAxes, 'Time(ms)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-125 125];
            app.UIAxes.YLim = [0 40];
            app.UIAxes.XTick = [-125 -100 -75 -50 -25 0 25 50 75 100 125];
            app.UIAxes.XTickLabel = {'-125'; '-100'; '-75'; '-50'; '-25'; '0'; '25'; '50'; '75'; '100'; '125'};
            app.UIAxes.YTick = [0 5 10 15 20 25 30 35 40];
            app.UIAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'RADAR Spectrogram (For Training)')
            xlabel(app.UIAxes_2, 'Freq(MHz)')
            ylabel(app.UIAxes_2, 'Time(ms)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-125 125];
            app.UIAxes_2.YLim = [0 40];
            app.UIAxes_2.XTick = [-125 -100 -75 -50 -25 0 25 50 75 100 125];
            app.UIAxes_2.XTickLabel = {'-125'; '-100'; '-75'; '-50'; '-25'; '0'; '25'; '50'; '75'; '100'; '125'};
            app.UIAxes_2.YTick = [0 5 10 15 20 25 30 35 40];
            app.UIAxes_2.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Combined Spectrogram (For Testing)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-125 125];
            app.UIAxes2.YLim = [0 40];
            app.UIAxes2.XTick = [-125 -100 -75 -50 -25 0 25 50 75 100 125];
            app.UIAxes2.XTickLabel = {'-125'; '-100'; '-75'; '-50'; '-25'; '0'; '25'; '50'; '75'; '100'; '125'};
            app.UIAxes2.YTick = [0 5 10 15 20 25 30 35 40];
            app.UIAxes2.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'True Labels')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [-125 125];
            app.UIAxes3.YLim = [0 40];
            app.UIAxes3.XTick = [-125 -100 -75 -50 -25 0 25 50 75 100 125];
            app.UIAxes3.XTickLabel = {'-125'; '-100'; '-75'; '-50'; '-25'; '0'; '25'; '50'; '75'; '100'; '125'};
            app.UIAxes3.YTick = [0 5 10 15 20 25 30 35 40];
            app.UIAxes3.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GenerateTrainingDataTab = uitab(app.TabGroup);
            app.GenerateTrainingDataTab.Title = 'Generate Training Data';
            app.DataFolderEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.DataFolderEditField.Value = 'TrainingData';
            app.SpectrogramSizePixelsEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.SpectrogramSizePixelsEditFieldLabel.Text = 'Spectrogram Size(Pixels)';
            app.SpectrogramSizePixelsEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.SpectrogramSizePixelsEditField.Value = '256  256';
            app.GeneralConfigurationLabel = uilabel(app.GenerateTrainingDataTab);
            app.GeneralConfigurationLabel.Text = 'General Configuration:';
            app.NRConfigurationLabel = uilabel(app.GenerateTrainingDataTab);
            app.NRConfigurationLabel.Text = 'NR Configuration:';
            app.RADARConfigurationLabel = uilabel(app.GenerateTrainingDataTab);
            app.RADARConfigurationLabel.Text = 'RADAR Configuration:';
            app.SweepIntervalDropDownLabel = uilabel(app.GenerateTrainingDataTab);
            app.SweepIntervalDropDownLabel.Text = 'Sweep Interval';
            app.SweepIntervalDropDown = uidropdown(app.GenerateTrainingDataTab);
            app.SweepIntervalDropDown.Items = {'Symmetric'};
            app.SweepIntervalDropDown.Value = 'Symmetric';
            app.ModulationDropDownLabel = uilabel(app.GenerateTrainingDataTab);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.GenerateTrainingDataTab);
            app.ModulationDropDown.Items = {'LFM'};
            app.ModulationDropDown.Value = 'LFM';
            app.DownlinkUplinkDropDownLabel = uilabel(app.GenerateTrainingDataTab);
            app.DownlinkUplinkDropDownLabel.Text = 'Downlink/Uplink';
            app.DownlinkUplinkDropDown = uidropdown(app.GenerateTrainingDataTab);
            app.DownlinkUplinkDropDown.Items = {'Downlink'};
            app.DownlinkUplinkDropDown.Value = 'Downlink';
            app.FrameDurationmsEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.FrameDurationmsEditFieldLabel.Text = 'Frame  Duration(ms)';
            app.FrameDurationmsEditField = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.FrameDurationmsEditField.ValueDisplayFormat = '%.0f';
            app.FrameDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @FrameDurationmsEditFieldValueChanged, true);
            app.FrameDurationmsEditField.Value = 20;
            app.SampleRateMSPSEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.SampleRateMSPSEditFieldLabel.Text = 'Sample Rate(MSPS)';
            app.SampleRateMSPSEditField = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.SampleRateMSPSEditField.ValueDisplayFormat = '%.3f';
            app.SampleRateMSPSEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateMSPSEditFieldValueChanged, true);
            app.SampleRateMSPSEditField.Value = 61.44;
            app.NoofFramesEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.NoofFramesEditFieldLabel.Text = 'No. of Frames';
            app.NoofFramesEditField = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%.0f';
            app.NoofFramesEditField.Value = 5;
            app.SubcarrierspacingKHzEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.SubcarrierspacingKHzEditFieldLabel.Text = 'Sub-carrier spacing(KHz)';
            app.SubcarrierspacingKHzEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.SubcarrierspacingKHzEditField.Value = '15 30';
            app.BandwidthMHzEditField_2Label = uilabel(app.GenerateTrainingDataTab);
            app.BandwidthMHzEditField_2Label.Text = 'Bandwidth(MHz)';
            app.BandwidthMHzEditField_2 = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.BandwidthMHzEditField_2.Value = '10 15 20';
            app.SSBPeriodmsEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.SSBPeriodmsEditFieldLabel.Text = 'SSB Period(ms)';
            app.SSBPeriodmsEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.SSBPeriodmsEditField.Value = '5 10 20 40 80 160';
            app.DopplerHzEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.DopplerHzEditFieldLabel.Text = 'Doppler(Hz)';
            app.DopplerHzEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.DopplerHzEditField.Value = '0 10 500';
            app.BandwidthrangeinMHzEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.BandwidthrangeinMHzEditFieldLabel.Text = 'Bandwidth(range in MHz)';
            app.BandwidthrangeinMHzEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.BandwidthrangeinMHzEditField.Value = '5 20';
            app.SweepDirectionEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.SweepDirectionEditFieldLabel.Text = 'Sweep Direction';
            app.SweepDirectionEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.SweepDirectionEditField.Value = 'Up  Down';
            app.PRFxPulseDurationLabel = uilabel(app.GenerateTrainingDataTab);
            app.PRFxPulseDurationLabel.Text = 'PRF x Pulse Duration';
            app.PRFxPulseDurationEditField = uieditfield(app.GenerateTrainingDataTab, 'text');
            app.PRFxPulseDurationEditField.Value = '1 0.5 0.25';
            app.CenterFreqGHzEditField_2Label = uilabel(app.GenerateTrainingDataTab);
            app.CenterFreqGHzEditField_2Label.Text = 'Center Freq(GHz)';
            app.CenterFreqGHzEditField_2 = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.CenterFreqGHzEditField_2.ValueDisplayFormat = '%.3f';
            app.CenterFreqGHzEditField_2.Value = 4;
            app.CollectSignalsButton = uibutton(app.GenerateTrainingDataTab, 'state');
            app.CollectSignalsButton.ValueChangedFcn = createCallbackFcn(app, @CollectSignalsButtonValueChanged, true);
            app.CollectSignalsButton.Text = 'Collect Signals';
            app.USRPDropDownLabel = uilabel(app.GenerateTrainingDataTab);
            app.USRPDropDownLabel.Text = 'USRP';
            app.USRPDropDown = uidropdown(app.GenerateTrainingDataTab);
            app.USRPDropDown.Items = {'X410', 'N320', 'N321', 'N310', 'X310 with UBX'};
            app.USRPDropDown.ValueChangedFcn = createCallbackFcn(app, @USRPDropDownValueChanged, true);
            app.USRPDropDown.Value = 'X410';
            app.HardwareConfigurationLabel = uilabel(app.GenerateTrainingDataTab);
            app.HardwareConfigurationLabel.Text = 'Hardware Configuration:';
            app.Button = uibutton(app.GenerateTrainingDataTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.USRPPresentDropDown_2Label = uilabel(app.GenerateTrainingDataTab);
            app.USRPPresentDropDown_2Label.Text = 'USRP Present';
            app.USRPPresentDropDown_2 = uidropdown(app.GenerateTrainingDataTab);
            app.USRPPresentDropDown_2.Items = {'No', 'Yes'};
            app.USRPPresentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @USRPPresentDropDown_2ValueChanged, true);
            app.USRPPresentDropDown_2.Value = 'No';
            app.RxGaindBEditFieldLabel_2 = uilabel(app.GenerateTrainingDataTab);
            app.RxGaindBEditFieldLabel_2.Text = 'Rx Gain(dB)';
            app.RxGaindBEditField_2 = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.RxGaindBEditField_2.ValueDisplayFormat = '%.3f';
            app.RxGaindBEditField_2.Value = 20;
            app.ConfigureHWButton_2 = uibutton(app.GenerateTrainingDataTab, 'push');
            app.ConfigureHWButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfigureHWButton_2Pushed, true);
            app.ConfigureHWButton_2.Enable = 'off';
            app.ConfigureHWButton_2.Text = 'Configure HW';
            app.TxGaindBEditField_2Label = uilabel(app.GenerateTrainingDataTab);
            app.TxGaindBEditField_2Label.Text = 'Tx Gain(dB)';
            app.TxGaindBEditField_2 = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.TxGaindBEditField_2.ValueDisplayFormat = '%.3f';
            app.TxGaindBEditField_2.Value = 60;
            app.RawSignalStoreIntervalEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.RawSignalStoreIntervalEditFieldLabel.Text = 'Raw Signal Store Interval';
            app.RawSignalStoreIntervalEditField = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.RawSignalStoreIntervalEditField.ValueDisplayFormat = '%.0f';
            app.RawSignalStoreIntervalEditField.Value = 1;
            app.StartIdxEditFieldLabel = uilabel(app.GenerateTrainingDataTab);
            app.StartIdxEditFieldLabel.Text = 'Start Idx';
            app.StartIdxEditField = uieditfield(app.GenerateTrainingDataTab, 'numeric');
            app.StartIdxEditField.ValueDisplayFormat = '%.0f';
            app.StartIdxEditField.Value = 1;
            app.TrainandEvaluateTab = uitab(app.TabGroup);
            app.TrainandEvaluateTab.Title = 'Train and Evaluate';
            app.GeneralConfigurationLabel_2 = uilabel(app.TrainandEvaluateTab);
            app.GeneralConfigurationLabel_2.Text = 'General Configuration:';
            app.DataFolderEditField_2Label = uilabel(app.TrainandEvaluateTab);
            app.DataFolderEditField_2Label.Text = 'Data Folder';
            app.DataFolderEditField_2 = uieditfield(app.TrainandEvaluateTab, 'text');
            app.DataFolderEditField_2.ValueChangedFcn = createCallbackFcn(app, @DataFolderEditField_2ValueChanged, true);
            app.BaseNetworkDropDownLabel = uilabel(app.TrainandEvaluateTab);
            app.BaseNetworkDropDownLabel.Text = 'Base Network';
            app.BaseNetworkDropDown = uidropdown(app.TrainandEvaluateTab);
            app.BaseNetworkDropDown.Items = {'resnet50'};
            app.BaseNetworkDropDown.Value = 'resnet50';
            app.TrainButton = uibutton(app.TrainandEvaluateTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ClassNamesEditFieldLabel = uilabel(app.TrainandEvaluateTab);
            app.ClassNamesEditFieldLabel.Text = 'Class Names';
            app.ClassNamesEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.ClassNamesEditField.Value = 'NR RADAR Noise';
            app.NeuralNetConfigurationLabel = uilabel(app.TrainandEvaluateTab);
            app.NeuralNetConfigurationLabel.Text = 'Neural Net Configuration:';
            app.SolverNameLabel = uilabel(app.TrainandEvaluateTab);
            app.SolverNameLabel.Text = 'Solver Name';
            app.SolverNameDropDown = uidropdown(app.TrainandEvaluateTab);
            app.SolverNameDropDown.Items = {'sgdm'};
            app.SolverNameDropDown.Value = 'sgdm';
            app.PlotsDropDownLabel = uilabel(app.TrainandEvaluateTab);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotsDropDown = uidropdown(app.TrainandEvaluateTab);
            app.PlotsDropDown.Items = {'training-progress'};
            app.PlotsDropDown.Value = 'training-progress';
            app.MaxEpochsEditFieldLabel = uilabel(app.TrainandEvaluateTab);
            app.MaxEpochsEditFieldLabel.Text = 'Max Epochs';
            app.MaxEpochsEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.MaxEpochsEditField.Value = '5';
            app.InitialLearningRateLabel = uilabel(app.TrainandEvaluateTab);
            app.InitialLearningRateLabel.Text = 'Initial Learning Rate';
            app.InitialLearningRateEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.InitialLearningRateEditField.Value = '0.02';
            app.ExecutionEnvironmentDropDownLabel = uilabel(app.TrainandEvaluateTab);
            app.ExecutionEnvironmentDropDownLabel.Text = 'Execution Environment';
            app.ExecutionEnvironmentDropDown = uidropdown(app.TrainandEvaluateTab);
            app.ExecutionEnvironmentDropDown.Items = {'auto', 'cpu', 'gpu', 'multi-gpu', 'parallel'};
            app.ExecutionEnvironmentDropDown.Value = 'auto';
            app.EvaluationConfigurationLabel = uilabel(app.TrainandEvaluateTab);
            app.EvaluationConfigurationLabel.Text = 'Evaluation Configuration:';
            app.EvaluationFolderEditFieldLabel = uilabel(app.TrainandEvaluateTab);
            app.EvaluationFolderEditFieldLabel.Text = 'Evaluation Folder';
            app.EvaluationFolderEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.EvaluationFolderEditField.Value = 'RADAR_NR';
            app.NetworkNameEditFieldLabel = uilabel(app.TrainandEvaluateTab);
            app.NetworkNameEditFieldLabel.Text = 'Network Name';
            app.NetworkNameEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.NetworkNameEditField.Value = 'net';
            app.ImageSizePixelsEditFieldLabel = uilabel(app.TrainandEvaluateTab);
            app.ImageSizePixelsEditFieldLabel.Text = 'Image Size(Pixels)';
            app.ImageSizePixelsEditField = uieditfield(app.TrainandEvaluateTab, 'text');
            app.ImageSizePixelsEditField.Value = '256  256';
            app.EvaluateButton = uibutton(app.TrainandEvaluateTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.LoadNetworkButton_3 = uibutton(app.TrainandEvaluateTab, 'push');
            app.LoadNetworkButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButton_3Pushed, true);
            app.LoadNetworkButton_3.Text = 'Load Network';
            app.Button_2 = uibutton(app.TrainandEvaluateTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.SoftwareTestTab = uitab(app.TabGroup);
            app.SoftwareTestTab.Title = 'Software Test';
            app.BrowseButton_3 = uibutton(app.SoftwareTestTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.InputSpectrogramTextAreaLabel = uilabel(app.SoftwareTestTab);
            app.InputSpectrogramTextAreaLabel.Text = 'Input Spectrogram';
            app.InputSpectrogramTextArea = uitextarea(app.SoftwareTestTab);
            app.InputSpectrogramTextArea.ValueChangedFcn = createCallbackFcn(app, @InputSpectrogramTextAreaValueChanged, true);
            app.InputSpectrogramTextArea.Value = {'nr_0.png'};
            app.ClassifyButton_2 = uibutton(app.SoftwareTestTab, 'push');
            app.ClassifyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButton_2Pushed, true);
            app.ClassifyButton_2.Text = 'Classify';
            app.NetworkEditField_2Label = uilabel(app.SoftwareTestTab);
            app.NetworkEditField_2Label.Text = 'Network ';
            app.NetworkEditField_2 = uieditfield(app.SoftwareTestTab, 'text');
            app.LoadNetworkButton = uibutton(app.SoftwareTestTab, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.LoopButton_2 = uibutton(app.SoftwareTestTab, 'state');
            app.LoopButton_2.ValueChangedFcn = createCallbackFcn(app, @LoopButton_2ValueChanged, true);
            app.LoopButton_2.Text = 'Loop';
            app.DataFolderEditField_3Label = uilabel(app.SoftwareTestTab);
            app.DataFolderEditField_3Label.Text = 'Data Folder';
            app.DataFolderEditField_3 = uieditfield(app.SoftwareTestTab, 'text');
            app.DataFolderEditField_3.ValueChangedFcn = createCallbackFcn(app, @DataFolderEditField_3ValueChanged, true);
            app.Button_3 = uibutton(app.SoftwareTestTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.HardwareTestTab = uitab(app.TabGroup);
            app.HardwareTestTab.Title = 'Hardware Test';
            app.BrowseButton = uibutton(app.HardwareTestTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.HardwareTestTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.Sig1TextAreaLabel = uilabel(app.HardwareTestTab);
            app.Sig1TextAreaLabel.Text = 'Sig-1';
            app.Sig1TextArea = uitextarea(app.HardwareTestTab);
            app.Sig1TextArea.Value = {'nr_0.bb'};
            app.Sig2TextAreaLabel = uilabel(app.HardwareTestTab);
            app.Sig2TextAreaLabel.Text = 'Sig-2';
            app.Sig2TextArea = uitextarea(app.HardwareTestTab);
            app.Sig2TextArea.Value = {'radar_0.bb'};
            app.GenerateButton_4 = uibutton(app.HardwareTestTab, 'push');
            app.GenerateButton_4.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_4Pushed, true);
            app.GenerateButton_4.Text = 'Generate';
            app.ClassifyandLabelButton = uibutton(app.HardwareTestTab, 'push');
            app.ClassifyandLabelButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyandLabelButtonPushed, true);
            app.ClassifyandLabelButton.Text = 'Classify and Label';
            app.SignalTypeDropDownLabel = uilabel(app.HardwareTestTab);
            app.SignalTypeDropDownLabel.Enable = 'off';
            app.SignalTypeDropDownLabel.Visible = 'off';
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.HardwareTestTab);
            app.SignalTypeDropDown.Items = {'RADAR and NR', 'NR Only', 'RADAR Only'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Enable = 'off';
            app.SignalTypeDropDown.Visible = 'off';
            app.SignalTypeDropDown.Value = 'RADAR and NR';
            app.USRPPresentDropDownLabel = uilabel(app.HardwareTestTab);
            app.USRPPresentDropDownLabel.Text = 'USRP Present';
            app.USRPPresentDropDown = uidropdown(app.HardwareTestTab);
            app.USRPPresentDropDown.Items = {'No', 'Yes'};
            app.USRPPresentDropDown.ValueChangedFcn = createCallbackFcn(app, @USRPPresentDropDownValueChanged, true);
            app.USRPPresentDropDown.Value = 'No';
            app.ConfigureHWButton = uibutton(app.HardwareTestTab, 'push');
            app.ConfigureHWButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureHWButtonPushed, true);
            app.ConfigureHWButton.Text = 'Configure HW';
            app.SampleRateMSPSEditField_2Label = uilabel(app.HardwareTestTab);
            app.SampleRateMSPSEditField_2Label.Text = 'Sample Rate(MSPS)';
            app.SampleRateMSPSEditField_2 = uieditfield(app.HardwareTestTab, 'numeric');
            app.SampleRateMSPSEditField_2.ValueDisplayFormat = '%.3f';
            app.SampleRateMSPSEditField_2.Value = 245.76;
            app.CenterFreqGHzEditFieldLabel = uilabel(app.HardwareTestTab);
            app.CenterFreqGHzEditFieldLabel.Text = 'Center Freq(GHz)';
            app.CenterFreqGHzEditField = uieditfield(app.HardwareTestTab, 'numeric');
            app.CenterFreqGHzEditField.ValueDisplayFormat = '%.3f';
            app.CenterFreqGHzEditField.Value = 4;
            app.RxGaindBEditFieldLabel = uilabel(app.HardwareTestTab);
            app.RxGaindBEditFieldLabel.Text = 'Rx Gain(dB)';
            app.RxGaindBEditField = uieditfield(app.HardwareTestTab, 'numeric');
            app.RxGaindBEditField.ValueDisplayFormat = '%.3f';
            app.RxGaindBEditField.Value = 20;
            app.Image = uiimage(app.HardwareTestTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'info.png');
            app.Image_2 = uiimage(app.HardwareTestTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'info.png');
            app.LoadNetworkButton_2 = uibutton(app.HardwareTestTab, 'push');
            app.LoadNetworkButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButton_2Pushed, true);
            app.LoadNetworkButton_2.Text = 'Load Network';
            app.NetworkEditFieldLabel = uilabel(app.HardwareTestTab);
            app.NetworkEditFieldLabel.Text = 'Network';
            app.NetworkEditField = uieditfield(app.HardwareTestTab, 'text');
            app.DataFolderEditField_4Label = uilabel(app.HardwareTestTab);
            app.DataFolderEditField_4Label.Text = 'Data Folder';
            app.DataFolderEditField_4 = uieditfield(app.HardwareTestTab, 'text');
            app.DataFolderEditField_4.ValueChangedFcn = createCallbackFcn(app, @DataFolderEditField_4ValueChanged, true);
            app.Button_4 = uibutton(app.HardwareTestTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '...';
            app.LoopButton = uibutton(app.HardwareTestTab, 'state');
            app.LoopButton.ValueChangedFcn = createCallbackFcn(app, @LoopButtonValueChanged, true);
            app.LoopButton.Text = 'Loop';
            app.TxGaindBEditFieldLabel = uilabel(app.HardwareTestTab);
            app.TxGaindBEditFieldLabel.Text = 'Tx Gain(dB)';
            app.TxGaindBEditField = uieditfield(app.HardwareTestTab, 'numeric');
            app.TxGaindBEditField.ValueDisplayFormat = '%.3f';
            app.TxGaindBEditField.Value = 60;
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecSenseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end